classdef classicalOrbitalElements < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClassicalOrbitalElementsLabel  matlab.ui.control.Label
        vkEditField                    matlab.ui.control.NumericEditField
        vkEditFieldLabel               matlab.ui.control.Label
        vjEditField                    matlab.ui.control.NumericEditField
        vjEditFieldLabel               matlab.ui.control.Label
        rkEditField                    matlab.ui.control.NumericEditField
        rkEditFieldLabel               matlab.ui.control.Label
        rjEditField                    matlab.ui.control.NumericEditField
        rjEditFieldLabel               matlab.ui.control.Label
        CalculateButton                matlab.ui.control.StateButton
        viEditField                    matlab.ui.control.NumericEditField
        viEditFieldLabel               matlab.ui.control.Label
        riEditField                    matlab.ui.control.NumericEditField
        riEditFieldLabel               matlab.ui.control.Label
        ArgumentofperigeeEditField     matlab.ui.control.NumericEditField
        ArgumentofperigeeEditFieldLabel  matlab.ui.control.Label
        RightascensionoftheascendingnodeEditField  matlab.ui.control.NumericEditField
        RightascensionoftheascendingnodeEditFieldLabel  matlab.ui.control.Label
        InclinationEditField           matlab.ui.control.NumericEditField
        InclinationEditFieldLabel      matlab.ui.control.Label
        TrueAnomalythetaEditField_2    matlab.ui.control.NumericEditField
        TrueAnomalythetaEditField_2Label  matlab.ui.control.Label
        EccentricityeEditField_2       matlab.ui.control.NumericEditField
        EccentricityeEditField_2Label  matlab.ui.control.Label
        AngularMomentumhEditField_2    matlab.ui.control.NumericEditField
        AngularMomentumhEditField_2Label  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function coes_from_sv(app) 

            ri = app.riEditField.Value;
            rj = app.rjEditField.Value;
            rk = app.rkEditField.Value;
            r = [ri, rj, rk];
            disp(r);

            vi = app.viEditField.Value;
            vj = app.vjEditField.Value;
            vk = app.vkEditField.Value;
            v = [vi, vj, vk];

            mu = 398600;
            rmag = norm(r);

            % Angular Momentum
            h = cross(r, v);
            hmag = norm(h);
            app.AngularMomentumhEditField_2.Value = hmag;

            % Eccentricity
            e = cross(v,h)/mu - r/rmag;
            emag = norm(e);
            app.EccentricityeEditField_2.Value = emag;

            % Inclination
            irad = acos(dot(h,[0,0,1])/hmag);
            ideg = rad2deg(irad);
            app.InclinationEditField.Value = ideg;

            % Right ascension of the ascending node
            N = cross([0,0,1], h);
            Nmag = norm(N);
            if Nmag == 0
                RAdeg = 0;
                app.RightascensionoftheascendingnodeEditField.Value = RAdeg;
            else
                RAtemp = acos(dot([1,0,0],N)/Nmag);
                if dot(N,[0,1,0]) >= 0
                    RArad = RAtemp;
                else
                    RArad = 2*pi - RAtemp;
                end

                RAdeg = rad2deg(RArad);
                app.RightascensionoftheascendingnodeEditField.Value = RAdeg;
            end


            % Argument of Perigee
            if Nmag == 0
                wdeg = 0;
                app.ArgumentofperigeeEditField.Value = wdeg;
                wrad = 0;
            else
                wtemp = acos(dot(N,e)/(Nmag*emag));
                if dot(e, [0,0,1]) >= 0
                    wrad = wtemp;
                else
                    wrad = 2*pi - wtemp;
                end

                wdeg = rad2deg(wrad);
                app.ArgumentofperigeeEditField.Value = wdeg;
            end


            % True Anomaly
            if Nmag == 0
                urad = 0;
            else
                utemp = acos(dot(N,r)/(Nmag*rmag));
                if dot(r, [0,0,1]) >= 0
                    urad = utemp;
                else
                    urad = 2*pi - utemp;
                end
            end

            TArad = urad - wrad;
            if TArad < 0
                TArad = TArad + 2*pi;
            end

            TAdeg = rad2deg(TArad);
            app.TrueAnomalythetaEditField_2.Value = TAdeg;
        end
        
        function sv_from_coes(app)
            mu = 398600;                
            RAdeg      = app.RightascensionoftheascendingnodeEditField.Value;
            ideg       = app.InclinationEditField.Value;
            wdeg       = app.ArgumentofperigeeEditField.Value;
            TAdeg      = app.TrueAnomalythetaEditField_2.Value;
            
            RA = deg2rad(RAdeg);
            i = deg2rad(ideg);
            w = deg2rad(wdeg);
            TA = deg2rad(TAdeg);
            h = app.AngularMomentumhEditField_2.Value;
            e = app.EccentricityeEditField_2.Value;

            rp = (h^2/mu) * (1/(1 + e*cos(TA))) * (cos(TA)*[1;0;0] + sin(TA)*[0;1;0]);
            vp = (mu/h) * (-sin(TA)*[1;0;0] + (e + cos(TA))*[0;1;0]);

            R3_W = [cos(RA) sin(RA) 0;
                -sin(RA) cos(RA) 0;
                0       0    1];

            R1_i = [1       0             0;
                0      cos(i)   sin(i);
                0     -sin(i)    cos(i)];

            R3_w = [cos(w) sin(w) 0;
                -sin(w) cos(w) 0;
                0       0    1];

            Q_pX = R3_W'*R1_i'*R3_w';

            r = Q_pX * rp;
            v = Q_pX * vp;

            r = r';
            v = v';
            
            app.riEditField.Value = r(1);
            app.rjEditField.Value = r(2);
            app.rkEditField.Value = r(3);
            
            app.viEditField.Value = v(1);
            app.vjEditField.Value = v(2);
            app.vkEditField.Value = v(3);
        end
        
        function plotOrbit(app)
            angles = linspace(0, 360);
            positions = zeros(3, 100);
            
            hmag = app.AngularMomentumhEditField_2.Value;
            emag = app.EccentricityeEditField_2.Value;
            RAdeg = app.RightascensionoftheascendingnodeEditField.Value;
            ideg = app.InclinationEditField.Value;
            wdeg = app.ArgumentofperigeeEditField.Value;
            

            for i = 1:100
                [r, ~] = sv_from_coe(hmag, emag, RAdeg, ideg, wdeg, angles(i));
                positions(1, i) = r(1);
                positions(2, i) = r(2);
                positions(3, i) = r(3);
            end
                      
            [X,Y,Z] = sphere;
            radius = 6378;
            X = X * radius;
            Y = Y * radius;
            Z = Z * radius;
            surf(X,Y,Z, 'parent', app.UIAxes);   
            axis(app.UIAxes, "equal");
            view(app.UIAxes, 130,15);
            hold(app.UIAxes, "on");
            plot3(app.UIAxes, positions(1, :), positions(2, :), positions(3, :), 'k', 'LineWidth', 2);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateCOE(app, event)
           if app.riEditField.Value == 0
            sv_from_coes(app);
           else          
            coes_from_sv(app);
           end
           
           plotOrbit(app);          

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AngularMomentumhEditField_2Label = uilabel(app.UIFigure);
            app.AngularMomentumhEditField_2Label.Text = 'Angular Momentum (h)';
            app.AngularMomentumhEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EccentricityeEditField_2Label = uilabel(app.UIFigure);
            app.EccentricityeEditField_2Label.Text = 'Eccentricity (e)';
            app.EccentricityeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TrueAnomalythetaEditField_2Label = uilabel(app.UIFigure);
            app.TrueAnomalythetaEditField_2Label.Text = 'True Anomaly (theta)';
            app.TrueAnomalythetaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.InclinationEditFieldLabel = uilabel(app.UIFigure);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightascensionoftheascendingnodeEditFieldLabel = uilabel(app.UIFigure);
            app.RightascensionoftheascendingnodeEditFieldLabel.Text = 'Right ascension of the ascending node';
            app.RightascensionoftheascendingnodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArgumentofperigeeEditFieldLabel = uilabel(app.UIFigure);
            app.ArgumentofperigeeEditFieldLabel.Text = 'Argument of perigee';
            app.ArgumentofperigeeEditField = uieditfield(app.UIFigure, 'numeric');
            app.riEditFieldLabel = uilabel(app.UIFigure);
            app.riEditFieldLabel.Text = 'ri';
            app.riEditField = uieditfield(app.UIFigure, 'numeric');
            app.viEditFieldLabel = uilabel(app.UIFigure);
            app.viEditFieldLabel.Text = 'vi';
            app.viEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateCOE, true);
            app.CalculateButton.Text = 'Calculate';
            app.rjEditFieldLabel = uilabel(app.UIFigure);
            app.rjEditFieldLabel.Text = 'rj';
            app.rjEditField = uieditfield(app.UIFigure, 'numeric');
            app.rkEditFieldLabel = uilabel(app.UIFigure);
            app.rkEditFieldLabel.Text = 'rk';
            app.rkEditField = uieditfield(app.UIFigure, 'numeric');
            app.vjEditFieldLabel = uilabel(app.UIFigure);
            app.vjEditFieldLabel.Text = 'vj';
            app.vjEditField = uieditfield(app.UIFigure, 'numeric');
            app.vkEditFieldLabel = uilabel(app.UIFigure);
            app.vkEditFieldLabel.Text = 'vk';
            app.vkEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClassicalOrbitalElementsLabel = uilabel(app.UIFigure);
            app.ClassicalOrbitalElementsLabel.Text = 'Classical Orbital Elements';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classicalOrbitalElements

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end